classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup2                    matlab.ui.container.TabGroup
        ManipulatorTab               matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        NumJointsEditField           matlab.ui.control.NumericEditField
        NumJointsEditFieldLabel      matlab.ui.control.Label
        SimulationTab                matlab.ui.container.Tab
        EnvironmentCheckBox          matlab.ui.control.CheckBox
        ExternalInputDropDown        matlab.ui.control.DropDown
        ExternalInputDropDownLabel   matlab.ui.control.Label
        UITable2                     matlab.ui.control.Table
        TimeStepdtEditField          matlab.ui.control.NumericEditField
        TimeStepdtEditFieldLabel     matlab.ui.control.Label
        DurationsEditField           matlab.ui.control.NumericEditField
        DurationsEditFieldLabel      matlab.ui.control.Label
        ControlTab                   matlab.ui.container.Tab
        TabGroup3                    matlab.ui.container.TabGroup
        SharedTab                    matlab.ui.container.Tab
        UITable4                     matlab.ui.control.Table
        TrajectoryDropDown           matlab.ui.control.DropDown
        TrajectoryDropDownLabel      matlab.ui.control.Label
        ModeDropDown                 matlab.ui.control.DropDown
        ModeDropDownLabel            matlab.ui.control.Label
        DynamicsTab                  matlab.ui.container.Tab
        ComplianceTab                matlab.ui.container.Tab
        UITable5                     matlab.ui.control.Table
        GravityCompensationCheckBox  matlab.ui.control.CheckBox
        ImpedanceTab                 matlab.ui.container.Tab
        IncludeCoriolisCentrifugalCheckBox  matlab.ui.control.CheckBox
        IncludeGravityCompensationCheckBox  matlab.ui.control.CheckBox
        UseInverseDynamicsCheckBox   matlab.ui.control.CheckBox
        UITable6                     matlab.ui.control.Table
        RUNButton                    matlab.ui.control.Button
        RESETButton                  matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        JointSpaceTab                matlab.ui.container.Tab
        TaskSpaceTab                 matlab.ui.container.Tab
        ForcesTab                    matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        
        function input = buildSimulationInput(app)
            input = create_sim_input();

            data = app.UITable.Data;
            N = size(data,1);
        
            input.robot.n_joints = N;
            input.robot.joint_types = string(data(:,1));
        
            % Safe scalar extraction
            input.robot.a = safe_copy(data,2,N);
            input.robot.alpha = safe_copy(data,3,N);
            input.robot.d = safe_copy(data,4,N);
            input.robot.theta = safe_copy(data,5,N);
        
            input.robot.masses = safe_copy(data,6,N);
        
            % COM (Nx3)
            comx = safe_copy(data,7,N);
            comy = safe_copy(data,8,N);
            comz = safe_copy(data,9,N);
            input.robot.COM = [comx, comy, comz];
        
            % Inertia (Nx3)
            Ix = safe_copy(data,10,N);
            Iy = safe_copy(data,11,N);
            Iz = safe_copy(data,12,N);
            input.robot.inertia = [Ix, Iy, Iz];
        
            input.robot.joint_min = safe_copy(data,13,N);
            input.robot.joint_max = safe_copy(data,14,N);

            data2 = app.UITable2.Data;
        
            input.sim.dt = app.TimeStepdtEditField.Value;
            input.sim.t_final = app.DurationsEditField.Value;
        
            input.sim.q0 = safe_copy(data2,1,N);
            input.sim.qd0 = safe_copy(data2,2,N);
        
            input.sim.external_tau = safe_copy(data2,3,N);
        
            input.sim.external_amplitude = safe_copy(data2,4,N);
            input.sim.external_frequency = safe_copy(data2,5,N);
        
            input.sim.environment_enabled = app.EnvironmentCheckBox.Value;
        
            input.sim.surface_pose = safe_copy(data2,6,N);
            input.sim.stiffness = safe_copy(data2,7,N);
            input.sim.damping = safe_copy(data2,8,N);

            input.control.mode = char(app.ModeDropDown.Value);
        
            data5 = app.UITable5.Data;
            input.control.kp = safe_copy(data5,1,N);
            input.control.kd = safe_copy(data5,2,N);
        
            input.control.gravity_comp = app.GravityCompensationCheckBox.Value;
        
            data6 = app.UITable6.Data;
            input.control.Md = safe_copy(data6,1,N);
            input.control.Bd = safe_copy(data6,2,N);
            input.control.Kd = safe_copy(data6,3,N);
        
            input.control.use_inverse_dynamics = app.UseInverseDynamicsCheckBox.Value;
            input.control.include_gravity = app.IncludeGravityCompensationCheckBox.Value;
            input.control.include_coriolis = app.IncludeCoriolisCentrifugalCheckBox.Value;

            input.trajectory.type = char(app.TrajectoryDropDown.Value);
        
            data4 = app.UITable4.Data;
        
            if isempty(data4) || size(data4,2) < 12
                error('Trajectory table (UITable4) is not initialized correctly.');
            end
        
            M = size(data4,1);
        
            % Position
            x = safe_copy(data4,1,M);
            y = safe_copy(data4,2,M);
            z = safe_copy(data4,3,M);
            input.trajectory.x_des = [x, y, z];
        
            % Velocity
            xd = safe_copy(data4,4,M);
            yd = safe_copy(data4,5,M);
            zd = safe_copy(data4,6,M);
            input.trajectory.xd_des = [xd, yd, zd];
        
            % Acceleration
            xdd = safe_copy(data4,7,M);
            ydd = safe_copy(data4,8,M);
            zdd = safe_copy(data4,9,M);
            input.trajectory.xdd_des = [xdd, ydd, zdd];
        
            input.trajectory.amplitude = safe_copy(data4,10,M);
            input.trajectory.frequency = safe_copy(data4,11,M);
            input.trajectory.step_time = safe_copy(data4,12,M);

            val_sim_input(input);
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumJointsEditField.Value = 3;
            NumJointsEditFieldValueChanged(app, []);
            app.UITable4.Data = num2cell(zeros(1,12));
            addpath(genpath('src/interfaces'));
            addpath(genpath('src/modules'));
            addpath(genpath('src/core'));
            addpath(genpath('src/utils'));
        end

        % Value changed function: NumJointsEditField
        function NumJointsEditFieldValueChanged(app, event)
            N = app.NumJointsEditField.Value;

            numParams1 = 14;
        
            data1 = cell(N, numParams1);
            data1(:,1) = {'R'};
            data1(:,2:end) = num2cell(zeros(N, numParams1-1));
        
            app.UITable.Data = data1;
            app.UITable.RowName = arrayfun(@(i) sprintf('Joint %d', i), 1:N, 'UniformOutput', false);
            app.UITable.ColumnFormat = [{'char'}, repmat({'numeric'}, 1, numParams1-1)];
        
            numParams2 = 8;
        
            data2 = num2cell(zeros(N, numParams2));
        
            app.UITable2.Data = data2;
            app.UITable2.RowName = app.UITable.RowName;
            app.UITable2.ColumnFormat = repmat({'numeric'}, 1, numParams2);
        
            numParams5 = 2;
        
            data5 = num2cell(zeros(N, numParams5));
        
            app.UITable5.Data = data5;
            app.UITable5.RowName = app.UITable.RowName;
            app.UITable5.ColumnFormat = repmat({'numeric'}, 1, numParams5);
        
            numParams6 = 3;
        
            data6 = num2cell(zeros(N, numParams6));
        
            app.UITable6.Data = data6;
            app.UITable6.RowName = app.UITable.RowName;
            app.UITable6.ColumnFormat = repmat({'numeric'}, 1, numParams6);

            app.UITable.ColumnEditable = true(1,14);
            app.UITable2.ColumnEditable = true(1,8);
            app.UITable5.ColumnEditable = true(1,2);
            app.UITable6.ColumnEditable = true(1,3);
            app.UITable4.ColumnEditable = true(1,12);
         
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.NumJointsEditField.Value = 3;

            NumJointsEditFieldValueChanged(app, []);

            app.TimeStepdtEditField.Value = 0.01;
            app.DurationsEditField.Value = 5.0;

            app.ModeDropDown.Value = 'Dynamics';
            app.TrajectoryDropDown.Value = 'Constant';
            app.ExternalInputDropDown.Value = 'No External Input';

            app.EnvironmentCheckBox.Value = false;
            app.GravityCompensationCheckBox.Value = false;
            app.UseInverseDynamicsCheckBox.Value = false;
            app.IncludeGravityCompensationCheckBox.Value = false;
            app.IncludeCoriolisCentrifugalCheckBox.Value = false;

            app.UITable4.Data = num2cell(zeros(size(app.UITable4.Data)));
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            input = buildSimulationInput(app);
            val_sim_input(input);
            output = run_simulation(input);

            t = output.time;
        
            % Joint plots
            figure;
            plot(t, output.q);
            title('Joint Positions');
            xlabel('Time');
            ylabel('q');
        
            figure;
            plot(t, output.qd);
            title('Joint Velocities');
        
            figure;
            plot(t, output.qdd);
            title('Joint Accelerations');
        
            % End-effector
            if isfield(output, 'end_effector')
                figure;
                plot(t, output.end_effector.position);
                title('End Effector Position');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x', 249};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RESETButton = uibutton(app.LeftPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.ManipulatorTab = uitab(app.TabGroup2);
            app.ManipulatorTab.Title = 'Manipulator';
            app.NumJointsEditFieldLabel = uilabel(app.ManipulatorTab);
            app.NumJointsEditFieldLabel.Text = 'Num Joints';
            app.NumJointsEditField = uieditfield(app.ManipulatorTab, 'numeric');
            app.NumJointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumJointsEditFieldValueChanged, true);
            app.UITable = uitable(app.ManipulatorTab);
            app.UITable.ColumnName = {'joint type'; 'a'; 'alpha'; 'd'; 'theta'; 'link mass'; 'COMx'; 'COMy'; 'COMz'; 'Ix'; 'Iy'; 'Iz'; 'joint min'; 'joint max'};
            app.UITable.RowName = {};
            app.SimulationTab = uitab(app.TabGroup2);
            app.SimulationTab.Title = 'Simulation';
            app.DurationsEditFieldLabel = uilabel(app.SimulationTab);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeStepdtEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeStepdtEditFieldLabel.Text = 'Time Step (dt)';
            app.TimeStepdtEditField = uieditfield(app.SimulationTab, 'numeric');
            app.UITable2 = uitable(app.SimulationTab);
            app.UITable2.ColumnName = {'q0'; 'qd0'; 'external torques'; 'amplitude'; 'frequency'; 'surface pose'; 'stiffness'; 'damping'};
            app.UITable2.RowName = {};
            app.ExternalInputDropDownLabel = uilabel(app.SimulationTab);
            app.ExternalInputDropDownLabel.Text = 'External Input';
            app.ExternalInputDropDown = uidropdown(app.SimulationTab);
            app.ExternalInputDropDown.Items = {'No External Input', 'Constant Torque', 'Sinusoidal Torque'};
            app.ExternalInputDropDown.Value = 'No External Input';
            app.EnvironmentCheckBox = uicheckbox(app.SimulationTab);
            app.EnvironmentCheckBox.Text = 'Environment';
            app.ControlTab = uitab(app.TabGroup2);
            app.ControlTab.Title = 'Control';
            app.TabGroup3 = uitabgroup(app.ControlTab);
            app.SharedTab = uitab(app.TabGroup3);
            app.SharedTab.Title = 'Shared';
            app.ModeDropDownLabel = uilabel(app.SharedTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.SharedTab);
            app.ModeDropDown.Items = {'Dynamics', 'Compliance', 'Impedance'};
            app.ModeDropDown.Value = 'Dynamics';
            app.TrajectoryDropDownLabel = uilabel(app.SharedTab);
            app.TrajectoryDropDownLabel.Text = 'Trajectory';
            app.TrajectoryDropDown = uidropdown(app.SharedTab);
            app.TrajectoryDropDown.Items = {'Constant', 'Step', 'Sinusoidal'};
            app.TrajectoryDropDown.Value = 'Constant';
            app.UITable4 = uitable(app.SharedTab);
            app.UITable4.ColumnName = {'x_des'; 'y_des'; 'z_des'; 'xd_des'; 'yd_des'; 'zd_des'; 'xdd_des'; 'ydd_des'; 'zdd_des'; 'amplitude'; 'frequency'; 'step time'};
            app.UITable4.RowName = {};
            app.DynamicsTab = uitab(app.TabGroup3);
            app.DynamicsTab.Title = 'Dynamics';
            app.ComplianceTab = uitab(app.TabGroup3);
            app.ComplianceTab.Title = 'Compliance';
            app.GravityCompensationCheckBox = uicheckbox(app.ComplianceTab);
            app.GravityCompensationCheckBox.Text = 'Gravity Compensation';
            app.UITable5 = uitable(app.ComplianceTab);
            app.UITable5.ColumnName = {'kP'; 'kD'};
            app.UITable5.RowName = {};
            app.ImpedanceTab = uitab(app.TabGroup3);
            app.ImpedanceTab.Title = 'Impedance';
            app.UITable6 = uitable(app.ImpedanceTab);
            app.UITable6.ColumnName = {'Md'; 'Bd'; 'Kd'};
            app.UITable6.RowName = {};
            app.UseInverseDynamicsCheckBox = uicheckbox(app.ImpedanceTab);
            app.UseInverseDynamicsCheckBox.Text = 'Use Inverse Dynamics';
            app.IncludeGravityCompensationCheckBox = uicheckbox(app.ImpedanceTab);
            app.IncludeGravityCompensationCheckBox.Text = 'Include Gravity Compensation';
            app.IncludeCoriolisCentrifugalCheckBox = uicheckbox(app.ImpedanceTab);
            app.IncludeCoriolisCentrifugalCheckBox.Text = 'Include Coriolis/Centrifugal';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.JointSpaceTab = uitab(app.TabGroup);
            app.JointSpaceTab.Title = 'Joint Space';
            app.TaskSpaceTab = uitab(app.TabGroup);
            app.TaskSpaceTab.Title = 'Task Space';
            app.ForcesTab = uitab(app.TabGroup);
            app.ForcesTab.Title = 'Forces';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end